classdef InteractiveBfieldViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        dyEditField               matlab.ui.control.NumericEditField
        dxEditField_2Label_2      matlab.ui.control.Label
        dzEditField               matlab.ui.control.NumericEditField
        dzEditFieldLabel          matlab.ui.control.Label
        AmLabel_3                 matlab.ui.control.Label
        AmLabel_2                 matlab.ui.control.Label
        AmLabel                   matlab.ui.control.Label
        MzEditField               matlab.ui.control.NumericEditField
        MzEditFieldLabel          matlab.ui.control.Label
        MyEditField               matlab.ui.control.NumericEditField
        MyEditFieldLabel          matlab.ui.control.Label
        MxEditField               matlab.ui.control.NumericEditField
        MxEditFieldLabel          matlab.ui.control.Label
        MagnetizationLabel        matlab.ui.control.Label
        EqualaspectratioCheckBox  matlab.ui.control.CheckBox
        vz2EditField              matlab.ui.control.NumericEditField
        vz2EditFieldLabel         matlab.ui.control.Label
        vz1EditField              matlab.ui.control.NumericEditField
        vz1EditFieldLabel         matlab.ui.control.Label
        z0EditField               matlab.ui.control.NumericEditField
        z0EditFieldLabel          matlab.ui.control.Label
        vy2EditField              matlab.ui.control.NumericEditField
        vy2EditFieldLabel         matlab.ui.control.Label
        vy1EditField              matlab.ui.control.NumericEditField
        vy1EditFieldLabel         matlab.ui.control.Label
        y0EditField               matlab.ui.control.NumericEditField
        y0EditFieldLabel          matlab.ui.control.Label
        vx2EditField              matlab.ui.control.NumericEditField
        vx2EditFieldLabel         matlab.ui.control.Label
        vx1EditField              matlab.ui.control.NumericEditField
        vx1EditFieldLabel         matlab.ui.control.Label
        dxEditField               matlab.ui.control.NumericEditField
        dxEditField_2Label        matlab.ui.control.Label
        x0EditField               matlab.ui.control.NumericEditField
        x0EditFieldLabel          matlab.ui.control.Label
        PrismDimensionsLabel      matlab.ui.control.Label
        DisplayRangeLabel         matlab.ui.control.Label
        BzAxes                    matlab.ui.control.UIAxes
        ByAxes                    matlab.ui.control.UIAxes
        BxAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myBox % The only magnetized rectangular prism associated with this GUI.
        Q % (3x3xN) Array of N Q matrices, each of which is 3x3.
        M % (3x1) magnetization vector.
    end
    properties (Dependent)
        survey % SurveyField object defining locations of query points
    end
    
    methods
        
        function survey = get.survey(app)
            % Compute array of grid points from display domain size.
            xv = unique(linspace( app.x0EditField.Value-app.dxEditField.Value, app.x0EditField.Value+app.dxEditField.Value ));
            yv = unique(linspace( app.y0EditField.Value-app.dyEditField.Value, app.y0EditField.Value+app.dyEditField.Value ));
            zv = unique(linspace( app.z0EditField.Value-app.dzEditField.Value, app.z0EditField.Value+app.dzEditField.Value ));
            survey = SurveyField( xv, yv, zv );
        end
        
    end
    
    methods (Access = private)
        
        function updateBox(app)
            % Adjust the box dimensions and/or location.
            app.myBox = MagBox( ...
                [ app.vx1EditField.Value app.vx2EditField.Value ], ...
                [ app.vy1EditField.Value app.vy2EditField.Value ], ...
                [ app.vz1EditField.Value app.vz2EditField.Value ], ...
                [ app.MxEditField.Value app.MyEditField.Value app.MzEditField.Value ]' );
            % Make sure the displays are up-to-date.
            app.vx1EditField.Value = app.myBox.vx(1);
            app.vx2EditField.Value = app.myBox.vx(2);
            app.vy1EditField.Value = app.myBox.vy(1);
            app.vy2EditField.Value = app.myBox.vy(2);
            app.vz1EditField.Value = app.myBox.vz(1);
            app.vz2EditField.Value = app.myBox.vz(2);
            % This also necessitates updating Q.
            updateQ(app);
        end
        function updateQ(app)
            % Sometimes you want to update Q even if you haven't changed
            % the box itself (i.e., when adjusting display limits).
            app.Q = app.myBox.computeQfield(app.survey.p);   
        end
        function updateM(app)
            % Adjust M all by itself.
            app.M = [ app.MxEditField.Value app.MyEditField.Value app.MzEditField.Value ]';
            app.myBox.M = app.M;
        end
        function updateBfigs(app)

            app.UIFigure.Pointer = 'watch';
            drawnow;

            % Compute B field from Q and M.
            B = MagSource.mu_naught_over_4pi * pagemtimes( app.Q, app.M );
            B = squeeze(B);

            % We'll need to know the grid we're working on.
            thisSurvey = app.survey;

            % Update figures for each component.
            cla(app.BxAxes);
            colorbar(app.BxAxes,'off');
            app.myBox.drawBox(app.BxAxes);
            MagBox.drawFieldContours(app.BxAxes,thisSurvey,B(1,:)*1e9,'scaling',MagSource.mu_naught_over_4pi*1e9,'cbar_label','(nT)');
            cla(app.ByAxes);
            colorbar(app.ByAxes,'off');
            app.myBox.drawBox(app.ByAxes);
            MagBox.drawFieldContours(app.ByAxes,thisSurvey,B(2,:)*1e9,'scaling',MagSource.mu_naught_over_4pi*1e9,'cbar_label','(nT)');
            cla(app.BzAxes);
            colorbar(app.BzAxes,'off');
            app.myBox.drawBox(app.BzAxes);
            MagBox.drawFieldContours(app.BzAxes,thisSurvey,B(3,:)*1e9,'scaling',MagSource.mu_naught_over_4pi*1e9,'cbar_label','(nT)');
            
            app.UIFigure.Pointer = 'arrow';

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Make sure all necessary libraries are on the Matlab path.
            setup;

            % Grab current information about the box's size and position.
            % This will automatically also update Q.
            updateBox(app);

            % Make sure we have the current M.
            updateM(app);

            % Refresh figures.
            updateBfigs(app);

        end

        % Value changed function: x0EditField
        function x0EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);           
        end

        % Value changed function: y0EditField
        function y0EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);
        end

        % Value changed function: z0EditField
        function z0EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);
        end

        % Value changed function: dxEditField
        function dxEditFieldValueChanged(app, event)
            if app.EqualaspectratioCheckBox.Value
                app.dyEditField.Value = app.dxEditField.Value;
                app.dzEditField.Value = app.dxEditField.Value;
            end
            updateQ(app);
            updateBfigs(app);
        end

        % Value changed function: dyEditField
        function dyEditFieldValueChanged(app, event)
            updateQ(app);
            updateBfigs(app);
        end

        % Value changed function: dzEditField
        function dzEditFieldValueChanged(app, event)
            updateQ(app);
            updateBfigs(app);
        end

        % Value changed function: EqualaspectratioCheckBox
        function EqualaspectratioCheckBoxValueChanged(app, event)
            % If the box is checked, we grey out the dy and dz boxes and
            % force them to match the dx box.
            if app.EqualaspectratioCheckBox.Value
                app.dyEditField.Enable = 'off';
                app.dzEditField.Enable = 'off';
                if app.dxEditField.Value == 0
                    app.dxEditField.Value = 10; % We can't allow a 0x0x0 survey field.
                end
                app.dyEditField.Value = app.dxEditField.Value;
                app.dzEditField.Value = app.dxEditField.Value;
                updateQ(app);
                updateBfigs(app);
            else
                app.dyEditField.Enable = 'on';
                app.dzEditField.Enable = 'on';
            end
        end

        % Value changed function: vx1EditField
        function vx1EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);
        end

        % Value changed function: vx2EditField
        function vx2EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);
        end

        % Value changed function: vy1EditField
        function vy1EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);            
        end

        % Value changed function: vy2EditField
        function vy2EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);
        end

        % Value changed function: vz1EditField
        function vz1EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);
        end

        % Value changed function: vz2EditField
        function vz2EditFieldValueChanged(app, event)
            updateBox(app);
            updateBfigs(app);
        end

        % Value changed function: MxEditField
        function MxEditFieldValueChanged(app, event)
            updateM(app);
            updateBfigs(app);
        end

        % Value changed function: MyEditField
        function MyEditFieldValueChanged(app, event)
            updateM(app);
            updateBfigs(app);
        end

        % Value changed function: MzEditField
        function MzEditFieldValueChanged(app, event)
            updateM(app);
            updateBfigs(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BxAxes = uiaxes(app.UIFigure);
            title(app.BxAxes, 'Bx')
            xlabel(app.BxAxes, 'X')
            ylabel(app.BxAxes, 'Y')
            zlabel(app.BxAxes, 'Z')
            app.ByAxes = uiaxes(app.UIFigure);
            title(app.ByAxes, 'By')
            xlabel(app.ByAxes, 'X')
            ylabel(app.ByAxes, 'Y')
            zlabel(app.ByAxes, 'Z')
            app.BzAxes = uiaxes(app.UIFigure);
            title(app.BzAxes, 'Bz')
            xlabel(app.BzAxes, 'X')
            ylabel(app.BzAxes, 'Y')
            zlabel(app.BzAxes, 'Z')
            app.DisplayRangeLabel = uilabel(app.UIFigure);
            app.DisplayRangeLabel.Text = 'Display Range';
            app.PrismDimensionsLabel = uilabel(app.UIFigure);
            app.PrismDimensionsLabel.Text = 'Prism Dimensions';
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @x0EditFieldValueChanged, true);
            app.x0EditField.Tooltip = {'Display range central x'};
            app.dxEditField_2Label = uilabel(app.UIFigure);
            app.dxEditField_2Label.Text = 'dx';
            app.dxEditField = uieditfield(app.UIFigure, 'numeric');
            app.dxEditField.ValueChangedFcn = createCallbackFcn(app, @dxEditFieldValueChanged, true);
            app.dxEditField.Tooltip = {'Display range in x'};
            app.dxEditField.Value = 20;
            app.vx1EditFieldLabel = uilabel(app.UIFigure);
            app.vx1EditFieldLabel.Text = 'vx1';
            app.vx1EditField = uieditfield(app.UIFigure, 'numeric');
            app.vx1EditField.ValueChangedFcn = createCallbackFcn(app, @vx1EditFieldValueChanged, true);
            app.vx1EditField.Tooltip = {'Lower x-face'};
            app.vx1EditField.Value = -3;
            app.vx2EditFieldLabel = uilabel(app.UIFigure);
            app.vx2EditFieldLabel.Text = 'vx2';
            app.vx2EditField = uieditfield(app.UIFigure, 'numeric');
            app.vx2EditField.ValueChangedFcn = createCallbackFcn(app, @vx2EditFieldValueChanged, true);
            app.vx2EditField.Tooltip = {'Upper x-face'};
            app.vx2EditField.Value = 3;
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.y0EditField.ValueChangedFcn = createCallbackFcn(app, @y0EditFieldValueChanged, true);
            app.y0EditField.Tooltip = {'Display range central y'};
            app.vy1EditFieldLabel = uilabel(app.UIFigure);
            app.vy1EditFieldLabel.Text = 'vy1';
            app.vy1EditField = uieditfield(app.UIFigure, 'numeric');
            app.vy1EditField.ValueChangedFcn = createCallbackFcn(app, @vy1EditFieldValueChanged, true);
            app.vy1EditField.Tooltip = {'Lower y-face'};
            app.vy1EditField.Value = -2;
            app.vy2EditFieldLabel = uilabel(app.UIFigure);
            app.vy2EditFieldLabel.Text = 'vy2';
            app.vy2EditField = uieditfield(app.UIFigure, 'numeric');
            app.vy2EditField.ValueChangedFcn = createCallbackFcn(app, @vy2EditFieldValueChanged, true);
            app.vy2EditField.Tooltip = {'Upper y-face'};
            app.vy2EditField.Value = 2;
            app.z0EditFieldLabel = uilabel(app.UIFigure);
            app.z0EditFieldLabel.Text = 'z0';
            app.z0EditField = uieditfield(app.UIFigure, 'numeric');
            app.z0EditField.ValueChangedFcn = createCallbackFcn(app, @z0EditFieldValueChanged, true);
            app.z0EditField.Tooltip = {'Display range central z'};
            app.vz1EditFieldLabel = uilabel(app.UIFigure);
            app.vz1EditFieldLabel.Text = 'vz1';
            app.vz1EditField = uieditfield(app.UIFigure, 'numeric');
            app.vz1EditField.ValueChangedFcn = createCallbackFcn(app, @vz1EditFieldValueChanged, true);
            app.vz1EditField.Tooltip = {'Lower z-face'};
            app.vz1EditField.Value = -1;
            app.vz2EditFieldLabel = uilabel(app.UIFigure);
            app.vz2EditFieldLabel.Text = 'vz2';
            app.vz2EditField = uieditfield(app.UIFigure, 'numeric');
            app.vz2EditField.ValueChangedFcn = createCallbackFcn(app, @vz2EditFieldValueChanged, true);
            app.vz2EditField.Tooltip = {'Upper z-face'};
            app.vz2EditField.Value = 1;
            app.EqualaspectratioCheckBox = uicheckbox(app.UIFigure);
            app.EqualaspectratioCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualaspectratioCheckBoxValueChanged, true);
            app.EqualaspectratioCheckBox.Text = 'Equal aspect ratio';
            app.EqualaspectratioCheckBox.Value = true;
            app.MagnetizationLabel = uilabel(app.UIFigure);
            app.MagnetizationLabel.Text = 'Magnetization';
            app.MxEditFieldLabel = uilabel(app.UIFigure);
            app.MxEditFieldLabel.Text = 'Mx';
            app.MxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MxEditField.ValueChangedFcn = createCallbackFcn(app, @MxEditFieldValueChanged, true);
            app.MxEditField.Tooltip = {'Upper x-face'};
            app.MxEditField.Value = 3;
            app.MyEditFieldLabel = uilabel(app.UIFigure);
            app.MyEditFieldLabel.Text = 'My';
            app.MyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MyEditField.ValueChangedFcn = createCallbackFcn(app, @MyEditFieldValueChanged, true);
            app.MyEditField.Tooltip = {'Upper y-face'};
            app.MzEditFieldLabel = uilabel(app.UIFigure);
            app.MzEditFieldLabel.Text = 'Mz';
            app.MzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MzEditField.ValueChangedFcn = createCallbackFcn(app, @MzEditFieldValueChanged, true);
            app.MzEditField.Tooltip = {'Upper z-face'};
            app.MzEditField.Value = -1;
            app.AmLabel = uilabel(app.UIFigure);
            app.AmLabel.Text = 'A/m';
            app.AmLabel_2 = uilabel(app.UIFigure);
            app.AmLabel_2.Text = 'A/m';
            app.AmLabel_3 = uilabel(app.UIFigure);
            app.AmLabel_3.Text = 'A/m';
            app.dzEditFieldLabel = uilabel(app.UIFigure);
            app.dzEditFieldLabel.Text = 'dz';
            app.dzEditField = uieditfield(app.UIFigure, 'numeric');
            app.dzEditField.ValueChangedFcn = createCallbackFcn(app, @dzEditFieldValueChanged, true);
            app.dzEditField.Enable = 'off';
            app.dzEditField.Tooltip = {'Display range in x'};
            app.dzEditField.Value = 20;
            app.dxEditField_2Label_2 = uilabel(app.UIFigure);
            app.dxEditField_2Label_2.Text = 'dy';
            app.dyEditField = uieditfield(app.UIFigure, 'numeric');
            app.dyEditField.ValueChangedFcn = createCallbackFcn(app, @dyEditFieldValueChanged, true);
            app.dyEditField.Enable = 'off';
            app.dyEditField.Tooltip = {'Display range in x'};
            app.dyEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InteractiveBfieldViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end